classdef DetectareContur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComputerUIFigure          matlab.ui.Figure
        ExitButton                matlab.ui.control.Button
        labelResult               matlab.ui.control.Label
        labelForContour           matlab.ui.control.Label
        labelForShapeVariability  matlab.ui.control.Label
        labelForLegendInfo        matlab.ui.control.Label
        labelForShape             matlab.ui.control.Label
        labelForThickness         matlab.ui.control.Label
        UploadImageButton         matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        app.labelForLegendInfo.Text = "<p><b><font style='color: purple; font-size: 16px;'>Legend of Diagnostic on Classical Ultrasound Metastates</font></b></p> " + ...
            "<p><b>Thickness</b><li><b><font style='color:green;'>Normal </b></font> - The thickness below 7 mm.</li><li><b><font style='color:red;'>Malignant</b></font> The thickness above 7 mm. </li></p> " + ...
            "<p><b>Shape</b><li><b><font style='color:green;'>Normal</b></font> (Elliptical shape)</li><li><b><font style='color:red;'>Malignant</b></font> (Spherical Shape) - Ratio between short axis / long axis > 0.5 </li></p> " + ...
            "<p><b>Colors of results</b><li><b><font style='color:green;'>Green</font></b> - The result is benign or normal.</li><li><font style='color:red;'><b>Red</font></b> - The result is reactive or malignant.</li></p>";
                                                                              
        app.labelForLegendInfo.Interpreter = "html";
        
        app.labelResult.Text = "<p><b><font style='color:purple;'> The result is processing. Please wait 5-10 seconds! </font></b></p>";
        app.labelResult.Interpreter ="html";
        
        app.labelForThickness.Text = "";
        app.labelForShape.Text = "";
        app.labelForShapeVariability.Text = "";
        app.labelForContour.Text = "";
        
        xlabel(app.UIAxes, "");
        ylabel(app.UIAxes, "");
        title(app.UIAxes, "Ultrasound image for processing");
     
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
          
        global originalEcoImage;
           
        [filename, pathname] = uigetfile('*.*', 'Specify an image file');
        filename = strcat(pathname, filename);
           
        if ~exist(filename, 'file')
            uiwait(msgbox("This file does not exist. Try to upload a new image!", "Warning"));
        	return;
        end
           
        originalEcoImage = imread(filename);
           
        imshow(originalEcoImage, 'Parent', app.UIAxes);
            
        extractedImage = bwareafilt(originalEcoImage > 0, 1, [1 1 1; 0 1 0; 1 1 1]);
        propsExtractedImage = regionprops(extractedImage, 'BoundingBox');
        boundingBox = propsExtractedImage.BoundingBox; 
        pause(5);
            
        % Crop original image
        croppedImage = imcrop(originalEcoImage, boundingBox);            
        
        [sizeRow, sizeColumn] = size(croppedImage); % take size of cropped image (399x564)
        opResize = imresize(croppedImage, [100 100]); % resized and eliminate the noise of image
        resizedImage = imresize(opResize, [sizeRow, sizeColumn]); %resize the image at original values
        
        % K means algorithm on resized_image without noise
        numberColors = 4;
        clustedImageWithKmeans = imsegkmeans(resizedImage, numberColors,'MaxIterations', 100); 
        
        appliedClustedImage = labeloverlay(resizedImage, clustedImageWithKmeans);
        clusteredFinalImage = rgb2gray(appliedClustedImage);
        %doubleClusteredImage = im2double(clusteredFinalImage); 

        binarizedClusteredImage = imbinarize(clusteredFinalImage);

        % Erode images
        % Erode vertical lines
        lengthSE = 2; 
        SE = ones(3, lengthSE);
        bwErodeVL = imerode(~binarizedClusteredImage, SE); 
        % Erode horizontal lines
        bwErodeHL = imerode(bwErodeVL, SE'); 
        % Dilate horizontal lines
        bwDilateHL = imdilate(bwErodeHL, SE'); 
        % Dilate vertical lines
        bwFinalResult = imdilate(bwDilateHL, SE);
      
        % Extract objects from binary image by descending size
        extractedObjectFromBwFinalResult = bwareafilt(bwFinalResult, 4);
                   
        % Label connected components in 2-D binary image
        labelOfBWResult = bwlabel(extractedObjectFromBwFinalResult); 
        
        % Detecting white shapes
        bwResult = bwFinalResult;
        bwResult(labelOfBWResult~=2 & labelOfBWResult~=3 & labelOfBWResult~=4) = 0;
        % put '0' black in points of clusters 2, 3, 4  from labelOfBWResult
        
        % Filling all holes of shapes
        bwResult = imfill(bwResult, 'holes');

        % ! Identifying Round Objects Algorithm !%
        
        % Trace region boundaries in binary image
        [exteriorBoundaryOfObject, labelMatrixOfObjects] = bwboundaries(bwResult); % extract boundaries from bw image bw22
                
        % Status about label Matrix of Objects
        statsOfLabelMatrixOfObjects = regionprops(labelMatrixOfObjects, 'Area', 'Centroid', 'Orientation');
        
        % Determine which Objects are Round
        metricVector = zeros(3, 1); areaVector = zeros(3, 1);
        
        % loop over the boundaries
        for k = 1 : length(exteriorBoundaryOfObject)
        
          % obtain (X,Y) boundary coordinates corresponding to label 'k'
          perimeterOfBoundaries = exteriorBoundaryOfObject{k};
        
          % compute a simple estimate of the object's perimeter
          deltaSq = diff(perimeterOfBoundaries).^2;    
          perimeter = sum(sqrt(sum(deltaSq,2)));
          
          % obtain the area calculation corresponding to label 'k'
          area = statsOfLabelMatrixOfObjects(k).Area;
          areaVector(k) = area;
          
          % compute the roundness metric
          metric = 4*pi*area/perimeter^2;
          
          % display the results
          %metricString = sprintf('%2.2f', metric);
          metricVector(k) = metric;
                     
          if (metric <= 0.07)
              bwResult(labelMatrixOfObjects == k) = 0;
              areaVector(k) = 0;
          end
           
          if (metric >= 0.9)
              bwResult(labelMatrixOfObjects==k) = 0;
              areaVector(k)=0;
          end

          if (area >= 40000)
               bwResult(labelMatrixOfObjects==k) = 0;
               areaVector(k)=0;
               bwAreaFinalShape = bwareafilt(bwResult, 1);
      
          end
           
          if (area == max(areaVector))
             bwAreaFinalShape = bwareafilt(bwResult, 2);
          end
        end
        
        % Extracted desired contour
        boundariesOfExtractedShape = bwboundaries(bwAreaFinalShape, 'noholes');
        numberOfBoundaries = size(boundariesOfExtractedShape, 1);
        minDistance = zeros(1);
        
        % ! Minimum distance between each pair of boundaries Algorithm !%
        % Find minimum distance between each pair of boundaries
        for bnd1 = 1 : numberOfBoundaries
        	for bnd2 = 1 : numberOfBoundaries
        		if bnd1 == bnd2
        			% Can't find distance between the region and itself
        			continue;
        		end
        		boundary1 = boundariesOfExtractedShape{bnd1};
        		boundary2 = boundariesOfExtractedShape{bnd2};
                
                overallMinDistance = inf; % Initialize.
        		boundary1x = boundary1(:, 2);
        		boundary1y = boundary1(:, 1);
        		
        		% For every point in boundary 2, find the distance to every point in boundary 1.
        		for k = 1 : size(boundary2, 1)
        			% Pick the next point on boundary 2.
        			boundary2x = boundary2(k, 2);
        			boundary2y = boundary2(k, 1);
        			% For this point, compute distances from it to all points in boundary 1.
        			allDistances = sqrt((boundary1x - boundary2x).^2 + (boundary1y - boundary2y).^2);
        			% Find closest point, min distance.
        			minDistance(k) = min(allDistances);
        			if (minDistance(k) < overallMinDistance)
        				overallMinDistance = minDistance(k);
        			end
        		end
        		% Find the overall min distance
        		minDistance = min(minDistance);               
        	end
        end
            
        existBwFinalShape = exist('bwAreaFinalShape', 'var');
        if (existBwFinalShape == 1)
            if (minDistance <= 27)
                bwAreaFinalShape = bwareafilt(bwAreaFinalShape, 2);
            else
                bwAreaFinalShape = bwareafilt(bwAreaFinalShape, 1);
            end
        end
       
        % ! Activecontour Algorithm !%
        % Set the mask for activecontour Algorithm !%
        maskOfShape = bwAreaFinalShape; % extracted mask
    
        % Finding the coordinates of island of pixels from maskOfShape
        [rowContourOfMask, columnContourOfMask] = find(maskOfShape == 1); % find all elements ofthe white section from contour
        positionOfCoordinatesOfContourOfMask = [rowContourOfMask, columnContourOfMask]; % row = y | col =x
        
        % Column 1 = y, column 2 = x
        [maxY, indexOfXinMaxY] = max(positionOfCoordinatesOfContourOfMask(:, 1));
        xInMaxY = positionOfCoordinatesOfContourOfMask(indexOfXinMaxY, 2);
        P_maxY = [xInMaxY, maxY];
        
        [minY, indexOfXinMinY] = min(positionOfCoordinatesOfContourOfMask(:, 1));
        xInMinY = positionOfCoordinatesOfContourOfMask(indexOfXinMinY, 2);
        P_minY = [xInMinY, minY];
        
        [maxX, indexOfYinMaxX] = max(positionOfCoordinatesOfContourOfMask(:, 2));
        yInMaxX = positionOfCoordinatesOfContourOfMask(indexOfYinMaxX, 1);
        P_maxX = [maxX, yInMaxX];
        
        [minX, indexOfYinMinX] = min(positionOfCoordinatesOfContourOfMask(:, 2));
        yInMinX = positionOfCoordinatesOfContourOfMask(indexOfYinMinX, 1);
        P_minX = [minX, yInMinX];

        % Centroid of the shape
        centroidShape = [sum(positionOfCoordinatesOfContourOfMask(:, 2))/length(positionOfCoordinatesOfContourOfMask(:, 2)), sum(positionOfCoordinatesOfContourOfMask(:, 1))/length(positionOfCoordinatesOfContourOfMask(:, 1))];

        % Creating the ellipse
        ellipseForIntensity = images.roi.Ellipse(app.UIAxes, 'Center', centroidShape, 'Semiaxes',[(P_maxX(1)-P_minX(1))/2 (P_maxY(2)-P_minY(2))/2]);
        maskOfEllipse = createMask(ellipseForIntensity);
        delete(ellipseForIntensity);
       
        regionEllipse = regionprops(maskOfEllipse, 'BoundingBox', 'PixelList');
        boundingBoxEllipse = regionEllipse.BoundingBox;
        sampleEllipse = imcrop(croppedImage, boundingBoxEllipse);
        
        % Intensity of pixels from ellipse
        intensityOfEllipsePixels = mean(mean(sampleEllipse));
        
        % Determining the smooth factor for active contour algoritm using intensity
        % of pixels from the ellipse 
        if (intensityOfEllipsePixels <= 38)
            smoothFactorForActiveContour = 4;
        else
            smoothFactorForActiveContour = 2;
        end
        
        % Active Contour Algorithm
        imageAfterActiveContour = activecontour(clusteredFinalImage, maskOfShape, 300, 'edge', ...
            'SmoothFactor', smoothFactorForActiveContour, 'ContractionBias', -1);
        
        % Finding the coordinates of island of pixels from image after
        % snake algorithm
        [rowContourAC, columnContourAC] = find(imageAfterActiveContour == 1); % find all elements ofthe white section from contour
        positionOfCoordinatesOfContour = [rowContourAC, columnContourAC]; % row = y | col =x
        
        % Column 1 = y, column 2 = x
        [maxY, indexOfXinMaxY] = max(positionOfCoordinatesOfContour(:, 1));
        xInMaxY = positionOfCoordinatesOfContour(indexOfXinMaxY, 2);
        P_maxY = [xInMaxY, maxY];
        
        [minY, indexOfXinMinY] = min(positionOfCoordinatesOfContour(:, 1));
        xInMinY = positionOfCoordinatesOfContour(indexOfXinMinY, 2);
        P_minY = [xInMinY, minY];
        
        [maxX, indexOfYinMaxX] = max(positionOfCoordinatesOfContour(:, 2));
        yInMaxX = positionOfCoordinatesOfContour(indexOfYinMaxX, 1);
        P_maxX = [maxX, yInMaxX];
        
        [minX, indexOfYinMinX] = min(positionOfCoordinatesOfContour(:, 2));
        yInMinX = positionOfCoordinatesOfContour(indexOfYinMinX, 1);
        P_minX = [minX, yInMinX];
        
        % The length in mm of each axis that compute the shape
        lengthOnYaxis = norm(P_minY - P_maxY) * 5 / 60; %% de pe imaginea originala, avem segmentele de deasupra care au 5 mm unul, apoi 60 pixeli are fiecare segment
        lengthOnXaxis = norm(P_minX - P_maxX) * 5 / 60;
        
        phiContour = bwdist(imageAfterActiveContour) - bwdist(1-imageAfterActiveContour) + im2double(imageAfterActiveContour);
        
        % Display the result
        imshow(croppedImage, 'Parent', app.UIAxes);
        hold(app.UIAxes, "on");
        contour(phiContour, 'Parent', app.UIAxes,  [0 0], 'Color', [1, 0.6, 0.25], 'LineWidth', 2);
        
        % Limits of the shapes
        plot(app.UIAxes, P_maxY(1), P_maxY(2), '*g', 'LineWidth', 3);
        plot(app.UIAxes, P_minY(1), P_minY(2), '*b', 'LineWidth', 3); 
        plot(app.UIAxes, P_maxX(1), P_maxX(2), '*m', 'LineWidth', 3);
        plot(app.UIAxes, P_minX(1), P_minX(2), '*r', 'LineWidth', 3);
       
        % Display short and long axis
        drawline(app.UIAxes,'Position',[P_minX; P_maxX],'Color', 'b'); % blue
        drawline(app.UIAxes,'Position',[P_minY; P_maxY],'Color', 'm'); % magenta
  
        % Ration between X axis and Y Axis
        if (lengthOnXaxis <= lengthOnYaxis) 
            shortAxis = lengthOnXaxis;
            longAxis = lengthOnYaxis;
            ratioBetweenAxis = shortAxis / longAxis; 
        else
            shortAxis = lengthOnYaxis;
            longAxis = lengthOnXaxis;
            ratioBetweenAxis = shortAxis / longAxis;  
        end
        
        % Condition for ratioBetweenAxis For Shape
        if (ratioBetweenAxis > 0.5)
           % Malignant sign
            app.labelForShape.Text ="<p><b>Shape</b><li><b><b><font style='color:black;'>Short axis measures "+ shortAxis + " mm.<b><font style='color:magenta;'> (Magenta line)</b></font></li></b></font><li><b><font style='color:black;'>Long axis measures "+ longAxis + " mm.<b><font style='color:blue;'> (Blue line)</b></font> </li></b></font><li><b><font style='color:red;'>Ratio between axis is equal to "+ ratioBetweenAxis + " and measures above 0.5.</b></font></li></p><li><b><font style='color:red;'>It is a reactive or malignant case, pointing out a spherical shape.</b></font></li></p> ";
            app.labelForShape.Interpreter ="html";
        else
            % Benign sign
            app.labelForShape.Text ="<p><b>Shape</b><li><b><b><font style='color:black;'>Short axis measures "+ shortAxis + " mm.<b><font style='color:magenta;'> (Magenta line)</b></font> </li></b></font><li><b><font style='color:black;'>Long axis measures "+ longAxis + " mm.<b><font style='color:blue;'> (Blue line)</b></font> </li></b></font><li><b><font style='color:green;'>Ratio between axis is equal to "+ ratioBetweenAxis + " and measures below 0.5.</b></font></li></p><li><b><font style='color:green;'>It is a benign or normal case, pointing out a elliptical shape.</b></font></li></p> ";
            app.labelForShape.Interpreter ="html";
        end
        
        % Finding the thickness starting from minimum (from top) point on Y
        [rowPosCoordContour, colPosCoordContour] = find(positionOfCoordinatesOfContour == P_minY(1));
        coordsOfAllPointsOfMaxY = [rowPosCoordContour, colPosCoordContour];    
        maxYOfPMax = max(positionOfCoordinatesOfContour(coordsOfAllPointsOfMaxY(:,1), 1));
        P_thickness = [P_minY(1), maxYOfPMax];
       
        thicknessFactor = norm(P_thickness - P_minY) * 5 / 60;
        drawline(app.UIAxes, 'Position', [P_thickness; P_minY], 'Color','y');
        hold(app.UIAxes,"off");
        
        app.labelResult.Text ="<p><b><font style='color:blue; font-size:16px;'>Final results of diagnosis of the lymph nodes are</font></b></p>";
        
        % Condition for thickness factor
        if (thicknessFactor > 7)
            % Malignant lymph node              
            app.labelForThickness.Text = "<p><b>Thickness <font style='color:#EDB120;'>(Yellow Line)</font></b><li><b><font style='color:red;'>Thickness is equal to "+ thicknessFactor + " mm and measures above 7 mm. It is a malign sign.</b></font></li></p> ";
            app.labelForThickness.Interpreter ="html";
        else
            % Normal / Benign lymph noode
            app.labelForThickness.Text = "<p><b>Thickness <font style='color:#EDB120;'>(Yellow Line)</font></b><li><b><font style='color:green;'>Thickness is equal to "+ thicknessFactor + " mm and measures below 7 mm. It is a normal or bening sign.</b></font></li></p> ";
            app.labelForThickness.Interpreter ="html";
        end
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComputerUIFigure = uifigure('Visible', 'off');
            colormap(app.ComputerUIFigure, 'jet');
            app.ComputerUIFigure.Name = 'Detectia conturului automat al nodului limfatic';
            app.UIAxes = uiaxes(app.ComputerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'jet')
            app.UploadImageButton = uibutton(app.ComputerUIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.labelForThickness = uilabel(app.ComputerUIFigure);
            app.labelForShape = uilabel(app.ComputerUIFigure);
            app.labelForLegendInfo = uilabel(app.ComputerUIFigure);
            app.labelForLegendInfo.Interpreter = 'html';
            app.labelForLegendInfo.Text = {'test'; ''};
            app.labelForShapeVariability = uilabel(app.ComputerUIFigure);
            app.labelForContour = uilabel(app.ComputerUIFigure);
            app.labelResult = uilabel(app.ComputerUIFigure);
            app.labelResult.Text = 'Label2';
            app.ExitButton = uibutton(app.ComputerUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.ComputerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DetectareContur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComputerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComputerUIFigure)
        end
    end
end